classdef UI_ISA_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        SetMenu                       matlab.ui.container.Menu
        FrequencyMenu                 matlab.ui.container.Menu
        NmodesMenu                    matlab.ui.container.Menu
        NsizeMenu                     matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabMatProperties              matlab.ui.container.TabGroup
        PlainTab                      matlab.ui.container.Tab
        at11mmEditField               matlab.ui.control.NumericEditField
        at11mmEditFieldLabel          matlab.ui.control.Label
        vt1mmsEditField               matlab.ui.control.NumericEditField
        vt1mmsEditFieldLabel          matlab.ui.control.Label
        al11mmEditField               matlab.ui.control.NumericEditField
        al11mmEditFieldLabel          matlab.ui.control.Label
        al01mmEditField               matlab.ui.control.NumericEditField
        al01mmEditFieldLabel          matlab.ui.control.Label
        vl1mmsEditField               matlab.ui.control.NumericEditField
        vl1mmsEditFieldLabel          matlab.ui.control.Label
        d1gcm3EditField               matlab.ui.control.NumericEditField
        d1gcm3EditFieldLabel          matlab.ui.control.Label
        vl0mmsEditField               matlab.ui.control.NumericEditField
        vl0mmsEditFieldLabel          matlab.ui.control.Label
        d0gcm3EditField               matlab.ui.control.NumericEditField
        d0gcm3EditFieldLabel          matlab.ui.control.Label
        HostmatrixDropDown            matlab.ui.control.DropDown
        HostmatrixDropDownLabel       matlab.ui.control.Label
        HostmatrixDropDownLabel_2     matlab.ui.control.Label
        ParticlesDropDown             matlab.ui.control.DropDown
        CoreshellTab                  matlab.ui.container.Tab
        at12mmEditField_CS            matlab.ui.control.NumericEditField
        at21mmLabel                   matlab.ui.control.Label
        al12mmEditField_CS            matlab.ui.control.NumericEditField
        al21mmLabel                   matlab.ui.control.Label
        vt2mmsEditField_CS            matlab.ui.control.NumericEditField
        vt2mmsLabel                   matlab.ui.control.Label
        vl2mmsEditField_CS            matlab.ui.control.NumericEditField
        vl2mmsLabel                   matlab.ui.control.Label
        d2gcm3EditField_CS            matlab.ui.control.NumericEditField
        d2gcm3Label                   matlab.ui.control.Label
        at11mmEditField_CS            matlab.ui.control.NumericEditField
        at11mmEditField_2Label        matlab.ui.control.Label
        vt1mmsEditField_CS            matlab.ui.control.NumericEditField
        vt1mmsEditField_2Label        matlab.ui.control.Label
        al11mmEditField_CS            matlab.ui.control.NumericEditField
        al11mmEditField_2Label        matlab.ui.control.Label
        vl1mmsEditField_CS            matlab.ui.control.NumericEditField
        vl1mmsEditField_2Label        matlab.ui.control.Label
        d1gcm3EditField_CS            matlab.ui.control.NumericEditField
        d1gcm3EditField_2Label        matlab.ui.control.Label
        al01mmEditField_CS            matlab.ui.control.NumericEditField
        al01mmEditField_2Label        matlab.ui.control.Label
        vl0mmsEditField_CS            matlab.ui.control.NumericEditField
        vl0mmsEditField_2Label        matlab.ui.control.Label
        d0gcm3EditField_CS            matlab.ui.control.NumericEditField
        d0gcm3EditField_2Label        matlab.ui.control.Label
        HostmatrixDropDownLabel_HS_2  matlab.ui.control.Label
        CoreDropDown                  matlab.ui.control.DropDown
        CoreradiusammEditField        matlab.ui.control.NumericEditField
        CoreradiusammEditFieldLabel   matlab.ui.control.Label
        HostmatrixDropDown_CS         matlab.ui.control.DropDown
        HostmatrixDropDown_2Label     matlab.ui.control.Label
        HostmatrixDropDownLabel_HS    matlab.ui.control.Label
        ParticlesDropDown_CS          matlab.ui.control.DropDown
        SpheroidsTab                  matlab.ui.container.Tab
        SamplepropertiesPanel         matlab.ui.container.Panel
        RadioDim                      matlab.ui.container.ButtonGroup
        Radio2D                       matlab.ui.control.RadioButton
        Radio3D                       matlab.ui.control.RadioButton
        CalculatingLamp               matlab.ui.control.Lamp
        CalculatingLampLabel          matlab.ui.control.Label
        StartCalcButton               matlab.ui.control.Button
        SizedispersionEditField       matlab.ui.control.NumericEditField
        SizedispersionEditFieldLabel  matlab.ui.control.Label
        VolumefractionEditField       matlab.ui.control.NumericEditField
        VolumefractionEditFieldLabel  matlab.ui.control.Label
        MeanradiusammEditField        matlab.ui.control.NumericEditField
        MeanradiusammEditFieldLabel   matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        EffectivepropertiesISATab     matlab.ui.container.Tab
        SaveresultsButton             matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        FieldcalculationTab           matlab.ui.container.Tab
        ShowfieldButton               matlab.ui.control.StateButton
        UIAxes_TW                     matlab.ui.control.UIAxes
        UIAxes_SW                     matlab.ui.control.UIAxes
        UIAxes_IW                     matlab.ui.control.UIAxes
        SpectralFunctionTab           matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        freq = linspace(0.5,5,1000);    % Number of points
        Nmodes = 10;                    % Number of modes
        Nr = 100;                       % Number of radii
        Rwidth = 3;                     % Width of size distribution
        k0a = 0;
        vL0 = 0;
        ls = 0;
        vph = 0;
        vgr = 0;
        ve = 0;
        ltr = 0;
        
    end
    
    methods (Access = private)
        
        function [freq,rho0,lambda0,mu0,rho1,lambda1,mu1] = Choix_Materiaux(Mat_n, Inc_n,freq, varargin)
            % Data concernant divers matériaux: solides, liquides, gas
            % Extraits des tables de Onda Corporation ou autres
            %
            % choix des matériaux :
            %
            % 'PDMS'
            % 'silicone'
            % 'epoxy'
            % 'AQUALENE'
            % 'zz_pure'
            % 'eau_mer'
            % 'gel_agar'
            % '47V20'
            % 'gel'
            % 'gel_test'
            % 'PMMA'
            % 'plomb'
            % 'verre'
            % 'FC40'
            % 'air_sec'
            % 'SF6'
            % 'FC40+SF6'
            % 'Sonovue'
            % 'mat_fortedensité'
            % 'aerogel'
            
            %% -------------- vevTeur frequence ---------------------------------------
            % nFREQ = 1000; %nFREQ = 1000;
            % fMIN = fMAX/nFREQ;
            % freq = linspace(fMIN,fMAX,nFREQ).';
            
            
            %% ------------ propriétés des  materiaux ---------------------------------
            [rho0, vL0, vT0, alphaL0, alphaT0] = material_No(Mat_n, freq);
            [rho1, vL1, vT1, alphaL1, alphaT1] = material_No(Inc_n, freq);


            %% ---- Passage en vL, mu et lambda complexes -----------------------------
            % !!! attention mu n'est pas complexe car alphaT n'est pas pris en compte
            
            kL0     = freq*2*pi./vL0 + 1i*alphaL0;
            kT0     = freq*2*pi./vT0 + 1i*alphaT0;
            mu0     = (rho0.*(freq*2*pi).^2) ./ kT0.^2 ;
            lambda0 = (rho0.*(freq*2*pi).^2) ./ kL0.^2 - 2.*mu0 ;
            
            kL1     = freq*2*pi./vL1 + 1i*alphaL1;
            kT1     = freq*2*pi./vT1 + 1i*alphaT1;
            mu1     = (rho1.*(freq*2*pi).^2) ./ kT1.^2 ;
            lambda1 = (rho1.*(freq*2*pi).^2) ./ kL1.^2 - 2.*mu1 ;
            
            %---------------------------------------
            %       DATA materiaux
            %---------------------------------------
            
            function [rho, vL, vT, alphaL, alphaT ] = material_No(Mat_n,freq)
                %  Data concernant divers matériaux: solides, liquides, gas
                %  Extraits des tables de Onda Corporation ou autres
                %  Les vitesses vL et vT sont données en mm.µs^(-1)
                %  Les modules (lambda et mu) sont donnés en MPa
                %  Les atténuation (alpha) sont données en Np.MHz^(-n).mm^(-1)
                
                tempArray = ones(1,length(freq));
                
                switch Mat_n
                    
                    case 'PDMS'
                        rho = 1.00 * tempArray;
                        vL = 1.000 * tempArray;
                        alphaL = 0.1 * freq.^(1.5);
                        vT = 0.030 * tempArray;
                        alphaT = 100 * freq.^(1.5);
                        
                    case 'Silicone'
                        %%% Silicone Bleu --- Mesures du 7 mars 2011 syr 5MHz_0_empty
                        rho = 1.11 * tempArray;                   %%% Mesuré au labo
                        vL = 1.00 * tempArray;                    %%% Mesures du 11/04/2011
                        alphaL = 0.0158 * freq.^(1.7);
                        mu = 1;                                   %%% (en MPa)
                        vT = sqrt(mu *1000)/1000 * tempArray;     %%% Valeur hypothetique
                        alphaT = 1000 * alphaL;                   %%% Valeur hypothetique
                        
                    case 'PVC'
                        %%% PVC de l'envLume couleur ivoire pour manip G' et G''
                        rho = 1.4 * tempArray;                    %%% Mesuré au labo
                        vL = 2.34 * tempArray;                    %%% Mesures du 11/04/2011
                        alphaL = 0.0158 * freq.^(1.7);
                        vT = 1.04 * tempArray;                    %%% Valeur hypothetique
                        alphaT = 100 * alphaL;
                        
                    case 'Epoxy'
                        %%% cf Sayers, J. Phys. D (1983)
                        rho = 1.20 * tempArray;
                        vL = 2.64 * tempArray;
                        alphaL = 0.0 * freq;
                        vT = 1.20 * tempArray;
                        alphaT = 0 * alphaL;
                        
                    case 'Aqualene'
                        %%% cf Olympus
                        rho = 0.92 * tempArray;
                        vL = 1.59 * tempArray;
                        alphaL = 0.003 * freq.^(1.5);
                        vT = 0.80 * tempArray;
                        alphaT = 2 * alphaL;                      %%% Valeur hypothetique
                        
                    case 'Cemcat'
                        %%% Polyuréthane --- Mesures du 26 juin 2012 1MHz
                        rho = 1.02 * tempArray;
                        vL = 1.41 * tempArray;
                        alphaL = freq.^(2);
                        vT = 0.110 * tempArray;                   %%% Cf These G. Lepert
                        alphaT = 200 * alphaL;                    %%% Valeur hypothetique
                        %vT = freq.^(2);
                        %mu = rho.*(vT.^2).*(1-i*freq.^(1/6)/3);
                        %alphaT = imag(2*pi*freq.*sqrt(rho./mu)) ;
                        
                    case 'Polystyrene'
                        rho = 1.05 * tempArray;
                        vL = 2.35 * tempArray;
                        alphaL = 0.0 * freq;
                        vT = 1.12 * tempArray;
                        alphaT = 0.0 * freq;                      %%% Pas d'onde transverse
                        
                    case 'Cork'
                        %%% avec E = 18.6 MPa & nu = 0
                        rho = 0.23 * tempArray;
                        vL = 0.284 * tempArray;
                        alphaL = freq.^(2);                       %%% Valeur hypothetique
                        vT = 0.201 * tempArray;
                        alphaT = 200 * alphaL;                    %%% Valeur hypothetique
                        
                        
                    case 'Water'
                        rho = 1.00 * tempArray;
                        vL = 1.5 * tempArray;
                        alphaL = 0.000003 * freq.^(2);
                        vT = 0.0 * tempArray;                     %%% Pas d'onde transverse
                        alphaT = 0.0 * tempArray;                 %%% Pas d'onde transverse
                        
                        
                    case 'Agar'
                        %%% cf Bouazzak, J. Phys. IV (1994)
                        rho = 1.005 * tempArray;
                        vL = 1.490 * tempArray;
                        alphaL = 0.00000633 * freq.^(2);
                        vT = 0.0 * tempArray;                     %%% Pas d'onde transverse
                        alphaT = 0.0 * freq;                      %%% Pas d'onde transverse
                        
                    case '47V20'
                        %%% cf Samet, CFA 2010
                        rho = 0.95 * tempArray;
                        vL = 0.976 * tempArray;
                        alphaL = 0.0007 * freq.^(1.87);
                        vT = 0.0 * tempArray;                     %%% Pas d'onde transverse
                        alphaT = 0.0 * freq;                      %%% Pas d'onde transverse
                        
                    case 'HMDSO'                                  %%% (Hexamethyldisiloxane)
                        rho = 0.760 * tempArray;
                        vL = 0.894 * tempArray;
                        alphaL = 0.0007 * freq.^(1.87);
                        vT = 0.0 * tempArray;                     %%% Pas d'onde transverse
                        alphaT = 0.0 * freq;                      %%% Pas d'onde transverse
                        
                    case 'Hair gel'
                        %%% Gel cheveux Auchan dilué
                        rho = 1.005 * tempArray;
                        vL = 1.48 * tempArray;%1.492 * tempArray;
                        alphaL = 0.000083 * freq.^(2);
                        vT = 0.0 * tempArray;                     %%% Pas d'onde transverse
                        alphaT = 0.0 * tempArray;                 %%% Pas d'onde transverse
                        
                        
                    case 'Xanthan'
                        %%% Gel cheveux Auchan dilué
                        rho = 1.005 * tempArray;
                        vL = 1.52 * tempArray;%1.492 * tempArray;
                        alphaL = 0.000083 * freq.^(2);
                        vT = 0.0 * tempArray;                     %%% Pas d'onde transverse
                        alphaT = 0.0 * tempArray;                 %%% Pas d'onde transverse
                        
                    case 'PMMA'
                        %%% cf He, J. Acoust. Soc. Am. (2000)
                        rho = 1.176 * tempArray;
                        vL = 2.75 * tempArray;
                        alphaL = 10.^(0.94 * freq/20)/10;
                        vT = 1.34 * tempArray;                    %%% Leymarie, Thèse 2002
                        alphaT = 2 * alphaL;                      %%% Valeur hypothetique
                        
                    case 'Glass'
                        %%% cf K. Hildebrand's thesis
                        rho = 2.50 * tempArray;
                        vL = 5.60 * tempArray;
                        alphaL = 7e-7 * freq.^(0);                %%% Valeur donnée à 2MHz
                        vT = 3.4 * tempArray;
                        alphaT = 0.0 * freq;                      %%% Attenuation negligee
                        
                    case 'Steel'
                        %%% cf Onda_Solids ("Steel - mild")
                        rho = 7.8 * tempArray;
                        vL = 5.9 * tempArray;
                        alphaL = 0.0 * freq;                      %%% Attenuation negligee
                        vT = 3.2 * tempArray;
                        alphaT = 0.0 * freq;                      %%% Attenuation negligee
                        
                    case 'Aluminum'
                        %%% cf Onda_Solids ("Steel - mild")
                        rho = 2.71 * tempArray;
                        vL = 6.40 * tempArray;
                        alphaL = 0.0 * freq;                      %%% Attenuation negligee
                        vT = 3.12 * tempArray;
                        alphaT = 0.0 * freq;                      %%% Attenuation negligee
                        
                    case 'Tungstene'
                        %%% cf Onda_Solids ("Tungsten")
                        rho = 19.4 * tempArray;
                        vL = 5.2 * tempArray;
                        alphaL = 0.0 * freq;                      %%% Attenuation negligee
                        vT = 2.9 * tempArray;
                        alphaT = 0.0 * freq;                      %%% Attenuation negligee
                        
                    case 'Lead'
                        %%% cf Onda_Solids ("Lead")
                        rho = 11.2 * tempArray;
                        vL = 2.2 * tempArray;
                        alphaL = 0.0 * freq;                      %%% Attenuation negligee
                        vT = 0.7 * tempArray;
                        alphaT = 0.0 * freq;                      %%% Attenuation negligee
                        
                    case 'Bismuth'
                        %%% cf Onda_Solids ("Bismuth")
                        rho = 9.8 * tempArray;
                        vL = 2.2 * tempArray;
                        alphaL = 0.0 * freq;                      %%% Attenuation negligee
                        vT = 1.1 * tempArray;
                        alphaT = 0.0 * freq;                      %%% Attenuation negligee
                        
                    case 'Baryte'
                        rho = 4.3 * tempArray;
                        vL = 7.0 * tempArray;
                        alphaL = 0.0 * freq;                      %%% Attenuation negligee
                        vT = 3.7 * tempArray;
                        alphaT = 0.0 * freq;                      %%% Attenuation negligee
                        
                    case 'Tin'
                        %%% cf Onda_Solids ("tin")
                        rho = 7.3 * tempArray;
                        vL = 3.3 * tempArray;
                        alphaL = 0.0 * freq;                      %%% Attenuation negligee
                        vT = 1.7 * tempArray;
                        alphaT = 0.0 * freq;                      %%% Attenuation negligee
                        
                    case 'BiPbInSnCd'
                        %%% cf Indium Corporation ("Indalloy 177")
                        rho = 9.16 * tempArray;
                        vL = 2.37 * tempArray;                    %%% mesure au labo
                        alphaL = 0.0 * freq;                      %%% Attenuation negligee
                        vT = 1.05 * tempArray;                    %%% mesure au labo
                        alphaT = 0.0 * freq;                      %%% Attenuation negligee
                        
                    case 'Mercury'
                        %%% cf Onda_Liquids (Mercury at 25.0°C)
                        rho = 13.5 * tempArray;
                        vL = 1.43 * tempArray;
                        alphaL = 0.0 * freq;                      %%% Attenuation negligee
                        vT = 0.0 * tempArray;
                        alphaT = 0.0 * freq;                      %%% Attenuation negligee
                        
                    case 'GaIn'
                        %%% cf Morley, Rev. Sci. Instrum. (2008)
                        rho = 6.28 * tempArray;
                        vL = 2.74 * tempArray;
                        alphaL = 0.0 * freq;                      %%% Attenuation negligee
                        vT = 0.0 * tempArray;
                        alphaT = 0.0 * freq;                      %%% Attenuation negligee
                        
                    case 'FC40'
                        %%% cf Fluorinert & Onda_Liquids
                        rho = 1.85 * tempArray;
                        vL = 0.64 * tempArray;
                        alphaL = 0.001 * freq.^(2);               %%% cf These Tallon
                        vT = 0.0 * tempArray;                     %%% Pas d'onde transverse
                        alphaT = 0.0 * freq;                      %%% Pas d'onde transverse
                        
                    case 'FC75'
                        %%% cf Fluorinert & Onda_Liquids
                        rho = 1.76 * tempArray;  % Mesuré
                        vL = 0.585 * tempArray;
                        alphaL = 0.0 * freq.^(0);
                        vT = 0.0 * tempArray;                     %%% Pas d'onde transverse
                        alphaT = 0.0 * freq;                      %%% Pas d'onde transverse
                        
                    case 'Ferrofluid'
                        %%% cf Brunet, Phys. Rev. Lett. (2013)
                        rho = 1.9 * tempArray;
                        vL = 0.670 * tempArray;
                        alphaL = 0.0057 * freq.^(-2);
                        vT = 0.0 * tempArray;                     %%% Pas d'onde transverse
                        alphaT = 0.0 * freq;                      %%% Pas d'onde transverse
                        
                    case 'Air'
                        %%% cf Onda_Gases ("Air - at 20°C")
                        rho = 0.001293 * tempArray;
                        vL = 0.344 * tempArray;
                        alphaL = 0.0 * freq;
                        vT = 0.0 * tempArray;                     %%% Pas d'onde transverse
                        alphaT = 0.0 * freq;                      %%% Pas d'onde transverse
                        
                    case 'SF6'
                        %%% cf http://www.sf6.fr
                        rho = 0.00627 * tempArray;
                        vL = 0.136 * tempArray;
                        vT = 0.0 * tempArray;                     %%% Pas d'onde transverse
                        alphaT = 0.0 * freq;                      %%% Pas d'onde transverse
                        
                    case 'Aerogel'
                        %%% cf Iino, Acoust. Sci. & Tech. (2011)
                        rho = 0.25 * tempArray;
                        vL = 0.15 * tempArray;
                        alphaL = 0.4949 * (2.5119) * freq.^(1.9);
                        vT = 0.058 * tempArray;                   %%% d apres coeff Poisson
                        alphaT = 10 * alphaL;                     %%% Valeur hypothetique
                        
                        
                    case 'Xerogel'
                        %%% Donneés extraites de fit de BM (réunion ANR 2014-02-13)
                        rho = 0.6 * tempArray;                    %%% thèse Simon (2014)
                        vL = 0.330 * tempArray;
                        alphaL = 0.12 * freq.^(1.1);              %%% cf Gomez, APL (2002)
                        vT = 0.183 * tempArray;                   %%% nu = 0.2780
                        alphaT = 0.226 * freq.^(0.5);             %%% cf Gomez, APL (2002)
                        
                    case 'HIPE'
                        %%% cf Brunet, Nature Materials 2015
                        rho = 0.60 * tempArray;                   %%% porosite = 40%
                        vL = 0.08 * tempArray;
                        alphaL = 60 * freq.^(1.5);
                        vT = 0.040 * tempArray;                   %%% nu = 0.3333
                        alphaT = 200 * freq.^(1.5);
                        
                    case 'Rubber'
                        %%% cf Liu, Science (2000)
                        rho = 1.3 * tempArray;
                        vL = 0.02 * tempArray;
                        alphaL = 0 * freq.^(2);
                        vT = 0.0 * tempArray;                     %%% nu = 0.5
                        alphaT = 0.0 * freq;                      %%% Pas d'onde transverse
                        
                end
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {622, 622};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {376, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: StartCalcButton
        function StartCalcButtonPushed(app, event)
            
            set(app.CalculatingLamp,'Color',[0.00,1.00,0.00])
            set(app.StartCalcButton,'enable','off')
            pause(0.1)
            
            if strcmp(app.LeftPanel.Children(1,1).SelectedTab.Title,'Plain')
                
                rho0 = get(app.d0gcm3EditField,'value');
                vl0 = get(app.vl0mmsEditField,'value');
                alphal0 = get(app.al01mmEditField,'value');
                
                rho1 = get(app.d1gcm3EditField,'value');
                vl1 = get(app.vl1mmsEditField,'value');
                alphal1 = get(app.al11mmEditField,'value');
                vt1 = get(app.vt1mmsEditField,'value');
                alphat1 = get(app.at11mmEditField,'value');
                
                M0 = [rho0,vl0,alphal0];
                M1 = [rho1,vl1,vt1,alphal1,alphat1];
                M2 = [0,0,0,0,0];
                
                Rm(1) =  app.MeanradiusammEditField.Value;
                Rm(2) = 0;
                Rm(3) = 0;
                
            elseif strcmp(app.LeftPanel.Children(1,1).SelectedTab.Title,'Core-shell')
                
                rho0 = get(app.d0gcm3EditField_CS,'value');
                vl0 = get(app.vl0mmsEditField_CS,'value');
                alphal0 = get(app.al01mmEditField_CS,'value');
                
                rho1 = get(app.d1gcm3EditField_CS,'value');
                vl1 = get(app.vl1mmsEditField_CS,'value');
                alphal1 = get(app.al11mmEditField_CS,'value');
                vt1 = get(app.vt1mmsEditField_CS,'value');
                alphat1 = get(app.at11mmEditField_CS,'value');
                
                rho2 = get(app.d2gcm3EditField_CS,'value');
                vl2 = get(app.vl2mmsEditField_CS,'value');
                alphal2 = get(app.al12mmEditField_CS,'value');
                vt2 = get(app.vt2mmsEditField_CS,'value');
                alphat2 = get(app.at12mmEditField_CS,'value');
                
                M0 = [rho0,vl0,alphal0];
                M1 = [rho1,vl1,vt1,alphal1,alphat1];
                M2 = [rho2,vl2,vt2,alphal2,alphat2];
                
                Rm(1) = app.MeanradiusammEditField.Value;
                Rm(2) = app.CoreradiusammEditField.Value;
                Rm(3) = 0;
                
            end
            
            if app.RadioDim.Buttons(1).Value
                
                [~,app.k0a,app.vL0,kISA,app.vph,app.vgr,app.ve,app.ltr] = k_ISA_3D(M0,M1,M2,...
                    app.Nmodes,app.freq,Rm,app.SizedispersionEditField.Value,app.VolumefractionEditField.Value,app.Nr,app.Rwidth);
                
            elseif app.RadioDim.Buttons(2).Value
                
                [~,app.k0a,app.vL0,kISA,app.vph,app.vgr,app.ve,app.ltr] = k_ISA_2D(M0,M1,M2,...
                    app.Nmodes,app.freq,Rm,app.SizedispersionEditField.Value,app.VolumefractionEditField.Value,app.Nr,app.Rwidth);
                
            end
            
            app.ls = 1./(2.*imag(kISA));
            
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            p11 = plot(app.k0a,app.ls.*real(kISA),'Parent',app.UIAxes,'DisplayName','l_s');
            p12 = plot(app.k0a,app.ltr.*real(kISA),'Parent',app.UIAxes,'DisplayName','l^*');
            set(app.UIAxes,'Yscale','log')
            xlim(app.UIAxes,[min(app.k0a) max(app.k0a)])
            ymax = 100.*min(app.ltr.*real(kISA));
            ymin = .1.*min(app.ltr.*real(kISA));
            ymax(ymax<0) = 100;
            ylim(app.UIAxes,[ymin,ymax])
            ytickformat(app.UIAxes,'%.2f')
            legend1 = legend(app.UIAxes,'show');
            set(legend1,'Location','north');
            hold(app.UIAxes,'off')
            
            cla(app.UIAxes_2)
            hold(app.UIAxes_2,'on')
            p21 = plot(app.k0a,app.vph./app.vL0,'Parent',app.UIAxes_2,'DisplayName','v_{ph}');
            p22 = plot(app.k0a,app.vgr./app.vL0,'Parent',app.UIAxes_2,'DisplayName','v_{gr}');
            p23 = plot(app.k0a,app.ve./app.vL0,'Parent',app.UIAxes_2,'DisplayName','v_e');
            xlim(app.UIAxes_2,[min(app.k0a) max(app.k0a)])
            ylim(app.UIAxes_2,[0.5.*min(app.vph./app.vL0),2.*max(app.vph./app.vL0)])
            ytickformat(app.UIAxes_2,'%.2f')
            legend2 = legend(app.UIAxes_2,'show');
            set(legend2,'Location','north');
            hold(app.UIAxes_2,'off')
            
            set(app.CalculatingLamp,'Color',[0.65,0.65,0.65])
            set(app.StartCalcButton,'enable','on')
            
        end

        % Value changed function: HostmatrixDropDown
        function HostmatrixDropDownValueChanged(app, event)
            
            value = app.HostmatrixDropDown.Value;
            
            if strcmp(value,'Custom')
                
                set(app.d0gcm3EditField,'enable','on','editable','on')
                set(app.vl0mmsEditField,'enable','on','editable','on')
                set(app.al01mmEditField,'enable','on','editable','on')
                
            else
                
                set(app.d0gcm3EditField,'enable', 'off','editable','off')
                set(app.vl0mmsEditField,'enable', 'off','editable','off')
                set(app.al01mmEditField,'enable', 'off','editable','off')
                
                f = 1;
                
                [~,rho0,lambda0,mu0,~,~,~] = Choix_Materiaux(value,...
                    app.ParticlesDropDown.Value,f);
                
                omega=2*pi*f;
                
                kl0 = omega./sqrt((lambda0+2*mu0)./rho0);
                vl0 = real(omega./kl0);
                
                set(app.d0gcm3EditField,'value',rho0)
                set(app.vl0mmsEditField,'value',vl0)
                set(app.al01mmEditField,'value',imag(kl0))
                
            end
            
        end

        % Value changed function: ParticlesDropDown
        function ParticlesDropDownValueChanged(app, event)
            value = app.ParticlesDropDown.Value;
            
            if strcmp(value,'Custom')
                
                set(app.d1gcm3EditField,'enable','on','editable', 'on')
                set(app.vl1mmsEditField,'enable','on','editable', 'on')
                set(app.vt1mmsEditField,'enable','on','editable', 'on')
                set(app.al11mmEditField,'enable','on','editable', 'on')
                set(app.at11mmEditField,'enable','on','editable', 'on')
                
            else
                
                set(app.d1gcm3EditField, 'enable', 'off')
                set(app.vl1mmsEditField, 'enable', 'off')
                set(app.vt1mmsEditField, 'enable', 'off')
                set(app.al11mmEditField, 'enable', 'off')
                set(app.at11mmEditField, 'enable', 'off')
                
                f = 1;
                
                [~,~,~,~,rho1,lambda1,mu1] = Choix_Materiaux(app.HostmatrixDropDown.Value,...
                    value,f);
                
                omega=2*pi*f;
                
                kl1 = omega./sqrt((lambda1+2*mu1)./rho1);
                kt1 = omega./sqrt(mu1./rho1);
                kt1(isinf(kt1)) = 0;
                vl1 = real(omega./kl1);
                vt1 = real(omega./kt1);
                vt1(isinf(vt1)) = 0;
                
                set(app.d1gcm3EditField,'value',rho1)
                set(app.vl1mmsEditField, 'value',vl1)
                set(app.vt1mmsEditField, 'value',vt1)
                set(app.al11mmEditField, 'value', imag(kl1))
                set(app.at11mmEditField, 'value', imag(kt1))
                
            end
            
        end

        % Menu selected function: FrequencyMenu
        function FrequencyMenuSelected(app, event)
            
            prompt = {'Min freq.:','Max freq.:','N points:'};
            dlgtitle = 'Set frequency (MHz)';
            dims = [1 75];
            definput = {'0.5','5','1000'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.freq = linspace(str2num(answer{1}),str2num(answer{2}),str2num(answer{3}));
            
        end

        % Menu selected function: NmodesMenu
        function NmodesMenuSelected(app, event)
            
            prompt = {'N modes.:'};
            dlgtitle = 'Number of harmonics';
            dims = [1 75];
            definput = {'10'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.Nmodes = str2num(answer{1});
            
        end

        % Menu selected function: NsizeMenu
        function NsizeMenuSelected(app, event)
            
            prompt = {'N sizes:','Distribution width:'};
            dlgtitle = 'Set size distribution';
            dims = [1 75];
            definput = {'100','3'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.Nr = str2num(answer{1});
            app.Rwidth = str2num(answer{2});
            
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            
            k0a = app.k0a;
            vL0 = app.vL0;
            ls = app.ls;
            vph = app.vph;
            vgr = app.vgr;
            ve = app.ve;
            ltr = app.ltr;
            
            uisave({'k0a','vL0','ls','vph','vgr','ve','ltr'},'resultsISA.mat');
            
        end

        % Value changed function: ShowfieldButton
        function ShowfieldButtonValueChanged(app, event)
            
            value = app.ShowfieldButton.Value;
            
            rho0 = get(app.d0gcm3EditField,'value');
            vl0 = get(app.vl0mmsEditField,'value');
            alphal0 = get(app.al01mmEditField,'value');
            
            rho1 = get(app.d1gcm3EditField,'value');
            vl1 = get(app.vl1mmsEditField,'value');
            alphal1 = get(app.al11mmEditField,'value');
            vt1 = get(app.vt1mmsEditField,'value');
            alphat1 = get(app.at11mmEditField,'value');
            
            if value
                
                [x,y,P0it,P0st,P1t,r,theta] = fct_Simu_deplacements(app.MeanradiusammEditField.Value,...
                    f,N,c0,c1,rho0,rho1,alphal0,alphal1,lim,pas,t,M);
                
            else
                
                
            end
            
        end

        % Value changed function: ParticlesDropDown_CS
        function ParticlesDropDown_CSValueChanged(app, event)
            value = app.ParticlesDropDown_CS.Value;

            if strcmp(value,'Custom')
                
                set(app.d1gcm3EditField_CS,'enable','on','editable', 'on')
                set(app.vl1mmsEditField_CS,'enable','on','editable', 'on')
                set(app.vt1mmsEditField_CS,'enable','on','editable', 'on')
                set(app.al11mmEditField_CS,'enable','on','editable', 'on')
                set(app.at11mmEditField_CS,'enable','on','editable', 'on')
                
            else
                
                set(app.d1gcm3EditField_CS, 'enable', 'off')
                set(app.vl1mmsEditField_CS, 'enable', 'off')
                set(app.vt1mmsEditField_CS, 'enable', 'off')
                set(app.al11mmEditField_CS, 'enable', 'off')
                set(app.at11mmEditField_CS, 'enable', 'off')
                
                f = 1;
                
                [~,~,~,~,rho1,lambda1,mu1] = Choix_Materiaux(app.HostmatrixDropDown.Value,...
                    value,f);
                
                omega=2*pi*f;
                
                kl1 = omega./sqrt((lambda1+2*mu1)./rho1);
                kt1 = omega./sqrt(mu1./rho1);
                kt1(isinf(kt1)) = 0;
                vl1 = real(omega./kl1);
                vt1 = real(omega./kt1);
                vt1(isinf(vt1)) = 0;
                
                set(app.d1gcm3EditField_CS,'value',rho1)
                set(app.vl1mmsEditField_CS, 'value',vl1)
                set(app.vt1mmsEditField_CS, 'value',vt1)
                set(app.al11mmEditField_CS, 'value', imag(kl1))
                set(app.at11mmEditField_CS, 'value', imag(kt1))
                
            end
        end

        % Value changed function: HostmatrixDropDown_CS
        function HostmatrixDropDown_CSValueChanged(app, event)
            value = app.HostmatrixDropDown_CS.Value;

            if strcmp(value,'Custom')
                
                set(app.d0gcm3EditField_CS,'enable','on','editable','on')
                set(app.vl0mmsEditField_CS,'enable','on','editable','on')
                set(app.al01mmEditField_CS,'enable','on','editable','on')
                
            else
                
                set(app.d0gcm3EditField_CS,'enable', 'off','editable','off')
                set(app.vl0mmsEditField_CS,'enable', 'off','editable','off')
                set(app.al01mmEditField_CS,'enable', 'off','editable','off')
                
                f = 1;
                
                [~,rho0,lambda0,mu0,~,~,~] = Choix_Materiaux(value,...
                    app.ParticlesDropDown.Value,f);
                
                omega=2*pi*f;
                
                kl0 = omega./sqrt((lambda0+2*mu0)./rho0);
                vl0 = real(omega./kl0);
                
                set(app.d0gcm3EditField_CS,'value',rho0)
                set(app.vl0mmsEditField_CS,'value',vl0)
                set(app.al01mmEditField_CS,'value',imag(kl0))
                
            end
        end

        % Value changed function: CoreDropDown
        function CoreDropDownValueChanged(app, event)
            value = app.CoreDropDown.Value;
            
            if strcmp(value,'Custom')
                
                set(app.d2gcm3EditField_CS,'enable','on','editable', 'on')
                set(app.vl2mmsEditField_CS,'enable','on','editable', 'on')
                set(app.vt2mmsEditField_CS,'enable','on','editable', 'on')
                set(app.al12mmEditField_CS,'enable','on','editable', 'on')
                set(app.at12mmEditField_CS,'enable','on','editable', 'on')
                
            else
                
                set(app.d2gcm3EditField_CS, 'enable', 'off')
                set(app.vl2mmsEditField_CS, 'enable', 'off')
                set(app.vt2mmsEditField_CS, 'enable', 'off')
                set(app.al12mmEditField_CS, 'enable', 'off')
                set(app.at12mmEditField_CS, 'enable', 'off')
                
                f = 1;
                
                [~,~,~,~,rho2,lambda2,mu2] = Choix_Materiaux(app.HostmatrixDropDown.Value,...
                    value,f);
                
                omega=2*pi*f;
                
                kl2 = omega./sqrt((lambda2+2*mu2)./rho2);
                kt2 = omega./sqrt(mu2./rho2);
                kt2(isinf(kt2)) = 0;
                vl2 = real(omega./kl2);
                vt2 = real(omega./kt2);
                vt2(isinf(vt2)) = 0;
                
                set(app.d2gcm3EditField_CS,'value',rho2)
                set(app.vl2mmsEditField_CS, 'value',vl2)
                set(app.vt2mmsEditField_CS, 'value',vt2)
                set(app.al12mmEditField_CS, 'value', imag(kl2))
                set(app.at12mmEditField_CS, 'value', imag(kt2))
            
            end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SetMenu = uimenu(app.Menu);
            app.SetMenu.Text = 'Set';
            app.FrequencyMenu = uimenu(app.SetMenu);
            app.FrequencyMenu.MenuSelectedFcn = createCallbackFcn(app, @FrequencyMenuSelected, true);
            app.FrequencyMenu.Text = 'Frequency';
            app.NmodesMenu = uimenu(app.SetMenu);
            app.NmodesMenu.MenuSelectedFcn = createCallbackFcn(app, @NmodesMenuSelected, true);
            app.NmodesMenu.Text = 'N modes';
            app.NsizeMenu = uimenu(app.SetMenu);
            app.NsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @NsizeMenuSelected, true);
            app.NsizeMenu.Text = 'N size';
            app.HelpMenu = uimenu(app.Menu);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Materials properties';
            app.SamplepropertiesPanel = uipanel(app.LeftPanel);
            app.SamplepropertiesPanel.Title = 'Sample properties';
            app.MeanradiusammEditFieldLabel = uilabel(app.SamplepropertiesPanel);
            app.MeanradiusammEditFieldLabel.Text = 'Mean radius a (mm)';
            app.MeanradiusammEditField = uieditfield(app.SamplepropertiesPanel, 'numeric');
            app.MeanradiusammEditField.Limits = [0 Inf];
            app.MeanradiusammEditField.Value = 0.17;
            app.VolumefractionEditFieldLabel = uilabel(app.SamplepropertiesPanel);
            app.VolumefractionEditFieldLabel.Text = 'Volume fraction (%)';
            app.VolumefractionEditField = uieditfield(app.SamplepropertiesPanel, 'numeric');
            app.VolumefractionEditField.Limits = [0 100];
            app.VolumefractionEditField.Value = 5;
            app.SizedispersionEditFieldLabel = uilabel(app.SamplepropertiesPanel);
            app.SizedispersionEditFieldLabel.Text = 'Size dispersion (%)';
            app.SizedispersionEditField = uieditfield(app.SamplepropertiesPanel, 'numeric');
            app.SizedispersionEditField.Limits = [0 100];
            app.StartCalcButton = uibutton(app.SamplepropertiesPanel, 'push');
            app.StartCalcButton.ButtonPushedFcn = createCallbackFcn(app, @StartCalcButtonPushed, true);
            app.StartCalcButton.Text = 'Start Calc.';
            app.CalculatingLampLabel = uilabel(app.SamplepropertiesPanel);
            app.CalculatingLampLabel.Text = 'Calculating';
            app.CalculatingLamp = uilamp(app.SamplepropertiesPanel);
            app.RadioDim = uibuttongroup(app.SamplepropertiesPanel);
            app.Radio3D = uiradiobutton(app.RadioDim);
            app.Radio3D.Text = '3D';
            app.Radio3D.Value = true;
            app.Radio2D = uiradiobutton(app.RadioDim);
            app.Radio2D.Text = '2D';
            app.TabMatProperties = uitabgroup(app.LeftPanel);
            app.PlainTab = uitab(app.TabMatProperties);
            app.PlainTab.Title = 'Plain';
            app.ParticlesDropDown = uidropdown(app.PlainTab);
            app.ParticlesDropDown.Items = {'Custom', 'Hair gel', 'Water', 'Air', 'FC40', 'GaIn', 'PDMS', 'Nylon', 'Silicone', 'PVC', 'Epoxy', 'Aqualene', 'Cemcat', 'Polystyrene', 'Cork', 'Agar', '47V20', 'HMDSO', 'Xanthan', 'PMMA', 'Glass', 'Steel', 'Aluminum', 'Tungstene', 'Lead', 'Bismuth', 'Baryte', 'Tin', 'BiPbInSnCd', 'Mercury', 'FC75', 'Ferrofluid', 'SF6', 'Aerogel', 'Xerogel', 'HIPE', 'Rubber'};
            app.ParticlesDropDown.ValueChangedFcn = createCallbackFcn(app, @ParticlesDropDownValueChanged, true);
            app.ParticlesDropDown.Value = 'FC40';
            app.HostmatrixDropDownLabel_2 = uilabel(app.PlainTab);
            app.HostmatrixDropDownLabel_2.Text = 'Particles';
            app.HostmatrixDropDownLabel = uilabel(app.PlainTab);
            app.HostmatrixDropDownLabel.Text = 'Host matrix';
            app.HostmatrixDropDown = uidropdown(app.PlainTab);
            app.HostmatrixDropDown.Items = {'Custom', 'Hair gel', 'Water', 'Air', 'FC40', 'GaIn', 'Agar', '47V20', 'HMDSO', 'Xanthan', 'Mercury', 'FC75', 'Ferrofluid', 'SF6', 'Rubber'};
            app.HostmatrixDropDown.ValueChangedFcn = createCallbackFcn(app, @HostmatrixDropDownValueChanged, true);
            app.HostmatrixDropDown.Value = 'Hair gel';
            app.d0gcm3EditFieldLabel = uilabel(app.PlainTab);
            app.d0gcm3EditFieldLabel.Tooltip = {''};
            app.d0gcm3EditFieldLabel.Text = {'d0 (g/cm^3)'; ''};
            app.d0gcm3EditField = uieditfield(app.PlainTab, 'numeric');
            app.d0gcm3EditField.Limits = [0 100];
            app.d0gcm3EditField.Editable = 'off';
            app.d0gcm3EditField.Enable = 'off';
            app.d0gcm3EditField.Value = 1;
            app.vl0mmsEditFieldLabel = uilabel(app.PlainTab);
            app.vl0mmsEditFieldLabel.Tooltip = {''};
            app.vl0mmsEditFieldLabel.Text = 'vl0 (mm/µs)';
            app.vl0mmsEditField = uieditfield(app.PlainTab, 'numeric');
            app.vl0mmsEditField.Limits = [0 100];
            app.vl0mmsEditField.Editable = 'off';
            app.vl0mmsEditField.Enable = 'off';
            app.vl0mmsEditField.Value = 1.48;
            app.d1gcm3EditFieldLabel = uilabel(app.PlainTab);
            app.d1gcm3EditFieldLabel.Tooltip = {''};
            app.d1gcm3EditFieldLabel.Text = {'d1 (g/cm^3)'; ''};
            app.d1gcm3EditField = uieditfield(app.PlainTab, 'numeric');
            app.d1gcm3EditField.Limits = [0 100];
            app.d1gcm3EditField.Editable = 'off';
            app.d1gcm3EditField.Enable = 'off';
            app.d1gcm3EditField.Value = 1.85;
            app.vl1mmsEditFieldLabel = uilabel(app.PlainTab);
            app.vl1mmsEditFieldLabel.Tooltip = {''};
            app.vl1mmsEditFieldLabel.Text = 'vl1 (mm/µs)';
            app.vl1mmsEditField = uieditfield(app.PlainTab, 'numeric');
            app.vl1mmsEditField.Limits = [0 100];
            app.vl1mmsEditField.Editable = 'off';
            app.vl1mmsEditField.Enable = 'off';
            app.vl1mmsEditField.Value = 0.64;
            app.al01mmEditFieldLabel = uilabel(app.PlainTab);
            app.al01mmEditFieldLabel.Tooltip = {''};
            app.al01mmEditFieldLabel.Text = 'al0 (1/mm)';
            app.al01mmEditField = uieditfield(app.PlainTab, 'numeric');
            app.al01mmEditField.Limits = [0 100];
            app.al01mmEditField.Editable = 'off';
            app.al01mmEditField.Enable = 'off';
            app.al01mmEditField.Value = 0.001;
            app.al11mmEditFieldLabel = uilabel(app.PlainTab);
            app.al11mmEditFieldLabel.Tooltip = {''};
            app.al11mmEditFieldLabel.Text = 'al1 (1/mm)';
            app.al11mmEditField = uieditfield(app.PlainTab, 'numeric');
            app.al11mmEditField.Limits = [0 100];
            app.al11mmEditField.Editable = 'off';
            app.al11mmEditField.Enable = 'off';
            app.al11mmEditField.Value = 1e-05;
            app.vt1mmsEditFieldLabel = uilabel(app.PlainTab);
            app.vt1mmsEditFieldLabel.Tooltip = {''};
            app.vt1mmsEditFieldLabel.Text = 'vt1 (mm/µs)';
            app.vt1mmsEditField = uieditfield(app.PlainTab, 'numeric');
            app.vt1mmsEditField.Limits = [0 100];
            app.vt1mmsEditField.Editable = 'off';
            app.vt1mmsEditField.Enable = 'off';
            app.at11mmEditFieldLabel = uilabel(app.PlainTab);
            app.at11mmEditFieldLabel.Tooltip = {''};
            app.at11mmEditFieldLabel.Text = 'at1 (1/mm)';
            app.at11mmEditField = uieditfield(app.PlainTab, 'numeric');
            app.at11mmEditField.Limits = [0 1000];
            app.at11mmEditField.Editable = 'off';
            app.at11mmEditField.Enable = 'off';
            app.CoreshellTab = uitab(app.TabMatProperties);
            app.CoreshellTab.Title = 'Core-shell';
            app.ParticlesDropDown_CS = uidropdown(app.CoreshellTab);
            app.ParticlesDropDown_CS.Items = {'Custom', 'Hair gel', 'Water', 'Air', 'FC40', 'GaIn', 'PDMS', 'Nylon', 'Silicone', 'PVC', 'Epoxy', 'Aqualene', 'Cemcat', 'Polystyrene', 'Cork', 'Agar', '47V20', 'HMDSO', 'Xanthan', 'PMMA', 'Glass', 'Steel', 'Aluminum', 'Tungstene', 'Lead', 'Bismuth', 'Baryte', 'Tin', 'BiPbInSnCd', 'Mercury', 'FC75', 'Ferrofluid', 'SF6', 'Aerogel', 'Xerogel', 'HIPE', 'Rubber'};
            app.ParticlesDropDown_CS.ValueChangedFcn = createCallbackFcn(app, @ParticlesDropDown_CSValueChanged, true);
            app.ParticlesDropDown_CS.Value = 'Epoxy';
            app.HostmatrixDropDownLabel_HS = uilabel(app.CoreshellTab);
            app.HostmatrixDropDownLabel_HS.Text = 'Particles';
            app.HostmatrixDropDown_2Label = uilabel(app.CoreshellTab);
            app.HostmatrixDropDown_2Label.Text = 'Host matrix';
            app.HostmatrixDropDown_CS = uidropdown(app.CoreshellTab);
            app.HostmatrixDropDown_CS.Items = {'Custom', 'Hair gel', 'Water', 'Air', 'FC40', 'GaIn', 'Agar', '47V20', 'HMDSO', 'Xanthan', 'Mercury', 'FC75', 'Ferrofluid', 'SF6', 'Rubber'};
            app.HostmatrixDropDown_CS.ValueChangedFcn = createCallbackFcn(app, @HostmatrixDropDown_CSValueChanged, true);
            app.HostmatrixDropDown_CS.Value = 'Hair gel';
            app.CoreradiusammEditFieldLabel = uilabel(app.CoreshellTab);
            app.CoreradiusammEditFieldLabel.Text = 'Core radius a (mm)';
            app.CoreradiusammEditField = uieditfield(app.CoreshellTab, 'numeric');
            app.CoreradiusammEditField.Limits = [0 Inf];
            app.CoreradiusammEditField.Value = 0.05;
            app.CoreDropDown = uidropdown(app.CoreshellTab);
            app.CoreDropDown.Items = {'Custom', 'Hair gel', 'Water', 'Air', 'FC40', 'GaIn', 'Nylon', 'PDMS', 'Silicone', 'PVC', 'Epoxy', 'Aqualene', 'Cemcat', 'Polystyrene', 'Cork', 'Agar', '47V20', 'HMDSO', 'Xanthan', 'PMMA', 'Glass', 'Steel', 'Aluminum', 'Tungstene', 'Lead', 'Bismuth', 'Baryte', 'Tin', 'BiPbInSnCd', 'Mercury', 'FC75', 'Ferrofluid', 'SF6', 'Aerogel', 'Xerogel', 'HIPE', 'Rubber'};
            app.CoreDropDown.ValueChangedFcn = createCallbackFcn(app, @CoreDropDownValueChanged, true);
            app.CoreDropDown.Value = 'FC40';
            app.HostmatrixDropDownLabel_HS_2 = uilabel(app.CoreshellTab);
            app.HostmatrixDropDownLabel_HS_2.Text = 'Core';
            app.d0gcm3EditField_2Label = uilabel(app.CoreshellTab);
            app.d0gcm3EditField_2Label.Tooltip = {''};
            app.d0gcm3EditField_2Label.Text = {'d0 (g/cm^3)'; ''};
            app.d0gcm3EditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.d0gcm3EditField_CS.Limits = [0 100];
            app.d0gcm3EditField_CS.Editable = 'off';
            app.d0gcm3EditField_CS.Enable = 'off';
            app.d0gcm3EditField_CS.Value = 1;
            app.vl0mmsEditField_2Label = uilabel(app.CoreshellTab);
            app.vl0mmsEditField_2Label.Tooltip = {''};
            app.vl0mmsEditField_2Label.Text = 'vl0 (mm/µs)';
            app.vl0mmsEditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.vl0mmsEditField_CS.Limits = [0 100];
            app.vl0mmsEditField_CS.Editable = 'off';
            app.vl0mmsEditField_CS.Enable = 'off';
            app.vl0mmsEditField_CS.Value = 1.48;
            app.al01mmEditField_2Label = uilabel(app.CoreshellTab);
            app.al01mmEditField_2Label.Tooltip = {''};
            app.al01mmEditField_2Label.Text = 'al0 (1/mm)';
            app.al01mmEditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.al01mmEditField_CS.Limits = [0 100];
            app.al01mmEditField_CS.Editable = 'off';
            app.al01mmEditField_CS.Enable = 'off';
            app.al01mmEditField_CS.Value = 0.001;
            app.d1gcm3EditField_2Label = uilabel(app.CoreshellTab);
            app.d1gcm3EditField_2Label.Tooltip = {''};
            app.d1gcm3EditField_2Label.Text = {'d1 (g/cm^3)'; ''};
            app.d1gcm3EditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.d1gcm3EditField_CS.Limits = [0 100];
            app.d1gcm3EditField_CS.Editable = 'off';
            app.d1gcm3EditField_CS.Enable = 'off';
            app.d1gcm3EditField_CS.Value = 1.2;
            app.vl1mmsEditField_2Label = uilabel(app.CoreshellTab);
            app.vl1mmsEditField_2Label.Tooltip = {''};
            app.vl1mmsEditField_2Label.Text = 'vl1 (mm/µs)';
            app.vl1mmsEditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.vl1mmsEditField_CS.Limits = [0 100];
            app.vl1mmsEditField_CS.Editable = 'off';
            app.vl1mmsEditField_CS.Enable = 'off';
            app.vl1mmsEditField_CS.Value = 2.64;
            app.al11mmEditField_2Label = uilabel(app.CoreshellTab);
            app.al11mmEditField_2Label.Tooltip = {''};
            app.al11mmEditField_2Label.Text = 'al1 (1/mm)';
            app.al11mmEditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.al11mmEditField_CS.Limits = [0 100];
            app.al11mmEditField_CS.Editable = 'off';
            app.al11mmEditField_CS.Enable = 'off';
            app.vt1mmsEditField_2Label = uilabel(app.CoreshellTab);
            app.vt1mmsEditField_2Label.Tooltip = {''};
            app.vt1mmsEditField_2Label.Text = 'vt1 (mm/µs)';
            app.vt1mmsEditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.vt1mmsEditField_CS.Limits = [0 100];
            app.vt1mmsEditField_CS.Editable = 'off';
            app.vt1mmsEditField_CS.Enable = 'off';
            app.vt1mmsEditField_CS.Value = 1.2;
            app.at11mmEditField_2Label = uilabel(app.CoreshellTab);
            app.at11mmEditField_2Label.Tooltip = {''};
            app.at11mmEditField_2Label.Text = 'at1 (1/mm)';
            app.at11mmEditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.at11mmEditField_CS.Limits = [0 1000];
            app.at11mmEditField_CS.Editable = 'off';
            app.at11mmEditField_CS.Enable = 'off';
            app.d2gcm3Label = uilabel(app.CoreshellTab);
            app.d2gcm3Label.Tooltip = {''};
            app.d2gcm3Label.Text = {'d2 (g/cm^3)'; ''};
            app.d2gcm3EditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.d2gcm3EditField_CS.Limits = [0 100];
            app.d2gcm3EditField_CS.Editable = 'off';
            app.d2gcm3EditField_CS.Enable = 'off';
            app.d2gcm3EditField_CS.Value = 1.85;
            app.vl2mmsLabel = uilabel(app.CoreshellTab);
            app.vl2mmsLabel.Tooltip = {''};
            app.vl2mmsLabel.Text = 'vl2 (mm/µs)';
            app.vl2mmsEditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.vl2mmsEditField_CS.Limits = [0 100];
            app.vl2mmsEditField_CS.Editable = 'off';
            app.vl2mmsEditField_CS.Enable = 'off';
            app.vl2mmsEditField_CS.Value = 0.64;
            app.vt2mmsLabel = uilabel(app.CoreshellTab);
            app.vt2mmsLabel.Tooltip = {''};
            app.vt2mmsLabel.Text = 'vt2 (mm/µs)';
            app.vt2mmsEditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.vt2mmsEditField_CS.Limits = [0 100];
            app.vt2mmsEditField_CS.Editable = 'off';
            app.vt2mmsEditField_CS.Enable = 'off';
            app.al21mmLabel = uilabel(app.CoreshellTab);
            app.al21mmLabel.Tooltip = {''};
            app.al21mmLabel.Text = 'al2 (1/mm)';
            app.al12mmEditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.al12mmEditField_CS.Limits = [0 100];
            app.al12mmEditField_CS.Editable = 'off';
            app.al12mmEditField_CS.Enable = 'off';
            app.al12mmEditField_CS.Value = 1e-05;
            app.at21mmLabel = uilabel(app.CoreshellTab);
            app.at21mmLabel.Tooltip = {''};
            app.at21mmLabel.Text = 'at2 (1/mm)';
            app.at12mmEditField_CS = uieditfield(app.CoreshellTab, 'numeric');
            app.at12mmEditField_CS.Limits = [0 1000];
            app.at12mmEditField_CS.Editable = 'off';
            app.at12mmEditField_CS.Enable = 'off';
            app.SpheroidsTab = uitab(app.TabMatProperties);
            app.SpheroidsTab.Title = 'Spheroids';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Results';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.EffectivepropertiesISATab = uitab(app.TabGroup);
            app.EffectivepropertiesISATab.Title = 'Effective properties (ISA)';
            app.UIAxes = uiaxes(app.EffectivepropertiesISATab);
            ylabel(app.UIAxes, 'Scatt. strength kl')
            app.UIAxes.XTickLabel = '';
            app.UIAxes_2 = uiaxes(app.EffectivepropertiesISATab);
            xlabel(app.UIAxes_2, 'Size Parameter k_0a')
            ylabel(app.UIAxes_2, 'v/v_{L0}')
            app.SaveresultsButton = uibutton(app.EffectivepropertiesISATab, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Text = 'Save results';
            app.FieldcalculationTab = uitab(app.TabGroup);
            app.FieldcalculationTab.Title = 'Field calculation';
            app.UIAxes_IW = uiaxes(app.FieldcalculationTab);
            title(app.UIAxes_IW, 'Incoming field')
            xlabel(app.UIAxes_IW, 'x/a')
            ylabel(app.UIAxes_IW, 'y/a')
            app.UIAxes_SW = uiaxes(app.FieldcalculationTab);
            title(app.UIAxes_SW, 'Scattered field')
            xlabel(app.UIAxes_SW, 'x/a')
            ylabel(app.UIAxes_SW, 'y/a')
            app.UIAxes_TW = uiaxes(app.FieldcalculationTab);
            title(app.UIAxes_TW, 'Total field')
            xlabel(app.UIAxes_TW, 'x/a')
            ylabel(app.UIAxes_TW, 'y/a')
            app.ShowfieldButton = uibutton(app.FieldcalculationTab, 'state');
            app.ShowfieldButton.ValueChangedFcn = createCallbackFcn(app, @ShowfieldButtonValueChanged, true);
            app.ShowfieldButton.Text = 'Show field';
            app.SpectralFunctionTab = uitab(app.TabGroup);
            app.SpectralFunctionTab.Title = 'Spectral Function';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_ISA_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end